classdef StartFromHere < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        OpenGift  matlab.ui.control.Button
        MscPlay   matlab.ui.control.Button
        BackHome  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Player % Description
        EffectOn % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [Y,Fs] = audioread('JingleBells.mp3');
            app.Player = audioplayer(Y,Fs);
            app.Player.play;
            app.EffectOn = false;
            set(app.UIAxes,'Xlim',[0,420]);
            set(app.UIAxes,'Ylim' ,[0,420]);
            hold(app.UIAxes,'off');
            imshow('Tree.png','parent',app.UIAxes);
            hold(app.UIAxes,'on');
                                    
            app.EffectOn = ~app.EffectOn;
            while app.EffectOn
                try
                    star.delete
                catch
                    % Do nothing
                end
                x = rand(30,1)*400+10;
                y = rand(30,1)*400+10;
                star = plot(app.UIAxes,x,y,'p','MarkerSize',16);
                star.MarkerEdgeColor = x(1:3)/420;
                star.MarkerFaceColor = star.MarkerEdgeColor;
                pause(0.5)
            end
        end

        % Button pushed function: OpenGift
        function OpenGiftButtonPushed(app, event)
            app.OpenGift.Visible = 'off';
            app.EffectOn = false;
            app.UIAxes.BackgroundColor = 'w';
            set(app.UIAxes,'XDir', 'normal');
            set(app.UIAxes,'YDir', 'normal');
            app.UIAxes.XLim = [-1.5 2];
            app.UIAxes.YLim = [-2 1];
            hold(app.UIAxes,'off');
            
            theta = linspace(pi/2,2.5*pi,100);
            rho = 1-sin(theta+pi/21);
            x1 = rho.*cos(theta);
            y1 = rho.*sin(theta);
            for k = 1:length(x1)
                plot(app.UIAxes,x1(1:k),y1(1:k),'Color','k','LineWidth',2);
                pause(0.01)
            end
            hold(app.UIAxes,'on');
            
            fill(app.UIAxes,x1,y1,'r','EdgeColor','k','LineWidth',2);
            pause(0.1)
            
            x2 = -0.35:0.05:0.3;
            y2 = 0.8*(x2-0.2).^2-0.25;
            for k = 1:length(x2)
                try
                    h1.detele
                catch
                    % Do nothing
                end
                h1 = plot(app.UIAxes,x2(1:k),y2(1:k),'Color','k','LineWidth',3);
                pause(0.01)
            end
            
            x3 = -0.08:0.05:0.5;
            y3 = -1.5*(x3-0.7).^2+1.5*0.7^2;
            for k = 1:length(x3)
                try
                    h2.detele
                catch
                    % Do nothing
                end
                h2 = plot(app.UIAxes,x3(1:k),y3(1:k),'Color','k','LineWidth',3);
                pause(0.01)
            end
            
            str1 = '祝魏老师';
            str2 = '圣诞快乐！';
            for k = 1:length(str1)
                text(app.UIAxes,1.3,1.1-0.3*k,str1(k),'FontSize',28,'Color','r')
                pause(0.2)
            end
            for k = 1:length(str2)
                text(app.UIAxes,1.6,0.7-0.3*k,str2(k),'FontSize',32,'Color','r')
                pause(0.2)
            end
            
            hold(app.UIAxes,'off')
            app.BackHome.Visible = 'on';
        end

        % Button pushed function: MscPlay
        function MscPlayButtonPushed(app, event)
            if app.Player.isplaying
                app.Player.pause
            else
                app.Player.resume
            end
        end

        % Button pushed function: BackHome
        function BackHomeButtonPushed(app, event)
            app.OpenGift.Visible = 'on';
            app.BackHome.Visible = 'off';
            set(app.UIAxes,'Xlim',[0,420]);
            set(app.UIAxes,'Ylim' ,[0,420]);
            hold(app.UIAxes,'off')
            imshow('Tree.png','parent',app.UIAxes)
            hold(app.UIAxes,'on')
            app.EffectOn = ~app.EffectOn;
            while app.EffectOn
                try
                    star.delete
                catch
                    % Do nothing
                end
                x = rand(30,1)*400+10;
                y = rand(30,1)*400+10;
                star = plot(app.UIAxes,x,y,'p','MarkerSize',16);
                star.MarkerEdgeColor = x(1:3)/420;
                star.MarkerFaceColor = star.MarkerEdgeColor;
                pause(0.5)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.OpenGift = uibutton(app.UIFigure, 'push');
            app.OpenGift.ButtonPushedFcn = createCallbackFcn(app, @OpenGiftButtonPushed, true);
            app.OpenGift.Icon = 'GiftIcon.png';
            app.OpenGift.Text = '';
            app.MscPlay = uibutton(app.UIFigure, 'push');
            app.MscPlay.ButtonPushedFcn = createCallbackFcn(app, @MscPlayButtonPushed, true);
            app.MscPlay.Icon = 'MusicIcon.png';
            app.MscPlay.Text = '';
            app.BackHome = uibutton(app.UIFigure, 'push');
            app.BackHome.ButtonPushedFcn = createCallbackFcn(app, @BackHomeButtonPushed, true);
            app.BackHome.Icon = 'ArrowIcon.png';
            app.BackHome.Visible = 'off';
            app.BackHome.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StartFromHere

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end