classdef SimPiano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        AppLabel        matlab.ui.control.Label
        Hz21s3Label     matlab.ui.control.Label
        Image           matlab.ui.control.Image
        CurrentKey      matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        BaseTime        matlab.ui.control.NumericEditField
        sLabel          matlab.ui.control.Label
        SampleFreqs     matlab.ui.control.NumericEditField
        HzLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        idle
        fs
        T
    end

    methods (Access = private)


        function wv = getWave(app,f)
            t = (0:1/app.fs:app.T)';

            wv = 0.6*sin(2*pi*f*t).*exp(-0.0007*2*pi*f*t);
            wv = wv + 0.3*sin(4*pi*f*t).*exp(-0.0007*2*pi*f*t);
            wv = wv + 0.1*sin(6*pi*f*t).*exp(-0.0007*2*pi*f*t);
            wv = wv + wv.^3;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.idle = true;
            app.fs = app.SampleFreqs.Value;
            app.T = app.BaseTime.Value;
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            % clear sound
            app.idle = true;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if app.idle
                key = event.Key;
                app.CurrentKey.Value = key;
                switch key
                    case 'z'
                        wv = app.getWave(130.81);
                    case 'x'
                        wv = app.getWave(146.83);
                    case 'c'
                        wv = app.getWave(164.81);
                    case 'v'
                        wv = app.getWave(174.61);
                    case 'b'
                        wv = app.getWave(196.00);
                    case 'n'
                        wv = app.getWave(220.00);
                    case 'm'
                        wv = app.getWave(246.94);

                        % ====
                    case 'a'
                        wv = app.getWave(261.63);
                    case 's'
                        wv = app.getWave(293.66);
                    case 'd'
                        wv = app.getWave(329.63);
                    case 'f'
                        wv = app.getWave(349.23);
                    case 'g'
                        wv = app.getWave(392.00);
                    case 'h'
                        wv = app.getWave(440.00);
                    case 'j'
                        wv = app.getWave(493.88);

                        % ========
                    case 'q'
                        wv = app.getWave(523.25);
                    case 'w'
                        wv = app.getWave(587.33);
                    case 'e'
                        wv = app.getWave(659.26);
                    case 'r'
                        wv = app.getWave(698.46);
                    case 't'
                        wv = app.getWave(783.99);
                    case 'y'
                        wv = app.getWave(880.00);
                    case 'u'
                        wv = app.getWave(987.77);
                    otherwise
                        wv = app.getWave(0);
                end
                sound(wv,app.fs)
                app.idle = false;
            end
        end

        % Value changed function: SampleFreqs
        function SampleFreqsValueChanged(app, event)
            value = app.SampleFreqs.Value;
            app.fs = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '采样频率 (Hz)';
            app.SampleFreqs = uieditfield(app.UIFigure, 'numeric');
            app.SampleFreqs.Limits = [0 Inf];
            app.SampleFreqs.ValueDisplayFormat = '%d';
            app.SampleFreqs.ValueChangedFcn = createCallbackFcn(app, @SampleFreqsValueChanged, true);
            app.SampleFreqs.Value = 44100;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '单音时间 (s)';
            app.BaseTime = uieditfield(app.UIFigure, 'numeric');
            app.BaseTime.Limits = [0 Inf];
            app.BaseTime.ValueDisplayFormat = '%.1f';
            app.BaseTime.Value = 1;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '当前按键';
            app.CurrentKey = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'keymap.png';
            app.Hz21s3Label = uilabel(app.UIFigure);
            app.Hz21s3Label.Text = {'使用说明:'; '    1. 设置音频采样率（默认44100 Hz）;'; '    2. 设置单个音节的时长（默认为1s）;'; '    3. 根据上面的键盘映射即可弹奏。'};
            app.AppLabel = uilabel(app.UIFigure);
            app.AppLabel.Text = {'目前的问题：'; '该 App 启动较慢，前期可能不出声音，等待片刻，'; '确认能够修改采样率时说明启动完成，可以弹奏。'; '（待修复）'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '注意：弹奏前鼠标点击空白区域，避免在输入框输入按键。';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimPiano

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end